classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Label_4        matlab.ui.control.Label
        hui_pow        matlab.ui.control.NumericEditField
        Label_9        matlab.ui.control.Label
        text_ts        matlab.ui.control.TextArea
        dat2png        matlab.ui.control.Button
        Label_5        matlab.ui.control.Label
        npic           matlab.ui.control.NumericEditField
        Label_6        matlab.ui.control.Label
        nskip          matlab.ui.control.NumericEditField
        Label_7        matlab.ui.control.Label
        hang           matlab.ui.control.NumericEditField
        Label_8        matlab.ui.control.Label
        lie            matlab.ui.control.NumericEditField
        ButtonGroup    matlab.ui.container.ButtonGroup
        buvr           matlab.ui.control.RadioButton
        ycvr           matlab.ui.control.RadioButton
        datLabel       matlab.ui.control.Label
        pow_ts         matlab.ui.control.TextArea
        ButtonGroup_2  matlab.ui.container.ButtonGroup
        bubn           matlab.ui.control.RadioButton
        ycbn           matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: dat2png
        function dat2pngPushed(app, event)
            app.text_ts.Value='...';
            if app.hui_pow.Value <= 0
                app.hui_pow.Value = 1;
                app.pow_ts.Value='no <=0,auto to 1';
            end
            for i = [1:app.npic.Value]
                fname_data = sprintf('%d.dat',i);
                fname_bz = sprintf('../bz/%d.txt',i);
                f=fopen(fname_data,'rb');
                data = fread(f,[app.hang.Value,app.lie.Value],'double',app.nskip.Value);
                fclose(f);
                
                data_gyh = (data-min(min(data)))/(max(max(data))-min(min(data)));
                data_pow=power(data_gyh,app.hui_pow.Value);
                img_uint8_gray = (uint8(data_pow*255));
                img_uint8(:,:,1) = img_uint8_gray;
                img_uint8(:,:,2) = img_uint8_gray;
                img_uint8(:,:,3) = img_uint8_gray;
                if app.ycbn.Value
                    bz=importdata(fname_bz);
                    img_uint8((bz(2)-1),[bz(1)-1:(bz(1)+bz(3))],1)=255;     % 上
                    img_uint8((bz(2)+bz(4)),[bz(1)-1:(bz(1)+bz(3))],1)=255; % 下
                    img_uint8([bz(2)-1:(bz(2)+bz(4))],bz(1)-1,1)=255; % 左
                    img_uint8([bz(2)-1:(bz(2)+bz(4))],(bz(1)+bz(3)),1)=255; %右
                    
                    img_uint8((bz(2)-1),[bz(1)-1:(bz(1)+bz(3))],2)=0;     % 上
                    img_uint8((bz(2)+bz(4)),[bz(1)-1:(bz(1)+bz(3))],2)=0; % 下
                    img_uint8([bz(2)-1:(bz(2)+bz(4))],bz(1)-1,2)=0; % 左
                    img_uint8([bz(2)-1:(bz(2)+bz(4))],(bz(1)+bz(3)),2)=0; %右
                    
                    img_uint8((bz(2)-1),[bz(1)-1:(bz(1)+bz(3))],3)=0;     % 上
                    img_uint8((bz(2)+bz(4)),[bz(1)-1:(bz(1)+bz(3))],3)=0; % 下
                    img_uint8([bz(2)-1:(bz(2)+bz(4))],bz(1)-1,3)=0; % 左
                    img_uint8([bz(2)-1:(bz(2)+bz(4))],(bz(1)+bz(3)),3)=0; %右
                end
                   
                if app.ycvr.Value
                    img_uint8=img_uint8';
                end
                fname_img = sprintf('%d.png',i);
                imwrite(img_uint8,fname_img);
            end
            app.text_ts.Value='OK';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '灰度值拉伸(正数比如0.5)';
            app.hui_pow = uieditfield(app.UIFigure, 'numeric');
            app.hui_pow.Value = 1;
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = {'提示'; '信息'};
            app.text_ts = uitextarea(app.UIFigure);
            app.dat2png = uibutton(app.UIFigure, 'push');
            app.dat2png.ButtonPushedFcn = createCallbackFcn(app, @dat2pngPushed, true);
            app.dat2png.Text = '转成png';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '图片数(比如1500)';
            app.npic = uieditfield(app.UIFigure, 'numeric');
            app.npic.Value = 1;
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '跳过数据数(比如106)';
            app.nskip = uieditfield(app.UIFigure, 'numeric');
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '几行(比如64)';
            app.hang = uieditfield(app.UIFigure, 'numeric');
            app.hang.Value = 64;
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '几列(比如1024)';
            app.lie = uieditfield(app.UIFigure, 'numeric');
            app.lie.Value = 1024;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '要转置吗';
            app.buvr = uiradiobutton(app.ButtonGroup);
            app.buvr.Text = '不要';
            app.buvr.Value = true;
            app.ycvr = uiradiobutton(app.ButtonGroup);
            app.ycvr.Text = '要的';
            app.datLabel = uilabel(app.UIFigure);
            app.datLabel.Text = '注：跟一堆dat放在一个文件夹目录';
            app.pow_ts = uitextarea(app.UIFigure);
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.Title = '要标注吗';
            app.bubn = uiradiobutton(app.ButtonGroup_2);
            app.bubn.Text = '不要';
            app.bubn.Value = true;
            app.ycbn = uiradiobutton(app.ButtonGroup_2);
            app.ycbn.Text = '要的';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end